classdef bodeplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataToolsPanel                  matlab.ui.container.Panel
        SelectmeasurementDropDown       matlab.ui.control.DropDown
        SelectmeasurementDropDownLabel  matlab.ui.control.Label
        ExportPanel                     matlab.ui.container.Panel
        AppendtoDropDown                matlab.ui.control.DropDown
        AppendtoDropDownLabel           matlab.ui.control.Label
        WorkspaceLabel                  matlab.ui.control.Label
        ExportdataButton                matlab.ui.control.Button
        PrintfigureButton               matlab.ui.control.Button
        PlotSettingsPanel               matlab.ui.container.Panel
        XaxisDropDown                   matlab.ui.control.DropDown
        XaxisDropDownLabel              matlab.ui.control.Label
        PlottypexyDropDown              matlab.ui.control.DropDown
        PlottypexyLabel                 matlab.ui.control.Label
        YaxisDropDown                   matlab.ui.control.DropDown
        YaxisDropDownLabel              matlab.ui.control.Label
        ConnectivityPanel               matlab.ui.container.Panel
        DG1000ZPanel                    matlab.ui.container.Panel
        IPadressEditField_2             matlab.ui.control.EditField
        IPadressEditField_2Label        matlab.ui.control.Label
        InterfaceDropDown_2Label        matlab.ui.control.Label
        InterfaceDropDown_2             matlab.ui.control.DropDown
        DS1000ZPanel                    matlab.ui.container.Panel
        IPadressEditField               matlab.ui.control.EditField
        IPadressEditFieldLabel          matlab.ui.control.Label
        InterfaceDropDown               matlab.ui.control.DropDown
        InterfaceDropDownLabel          matlab.ui.control.Label
        AquisitionSettingsPanel         matlab.ui.container.Panel
        NoiseRejectCheckBox             matlab.ui.control.CheckBox
        OutputImpedanceDropDown         matlab.ui.control.DropDown
        OutputImpedanceDropDownLabel    matlab.ui.control.Label
        VoffEditField                   matlab.ui.control.NumericEditField
        VoffEditFieldLabel              matlab.ui.control.Label
        VppEditField                    matlab.ui.control.NumericEditField
        VppEditFieldLabel               matlab.ui.control.Label
        SampledistributionDropDown      matlab.ui.control.DropDown
        SampledistributionDropDownLabel  matlab.ui.control.Label
        Ch1AttenuationDropDown          matlab.ui.control.DropDown
        Ch1AttenuationDropDownLabel     matlab.ui.control.Label
        Ch2AttenuationDropDown          matlab.ui.control.DropDown
        Ch2AttenuationDropDownLabel     matlab.ui.control.Label
        EnhancedautoscalingCheckBox     matlab.ui.control.CheckBox
        SamplesEditField                matlab.ui.control.NumericEditField
        SamplesEditFieldLabel           matlab.ui.control.Label
        StopfrequencyEditField          matlab.ui.control.NumericEditField
        StopfrequencyEditFieldLabel     matlab.ui.control.Label
        StartfrequencyEditField         matlab.ui.control.NumericEditField
        StartfrequencyEditFieldLabel    matlab.ui.control.Label
        LockfrontpanelsCheckBox         matlab.ui.control.CheckBox
        MbandwidthlimitCheckBox         matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Progress Bar
        pT                      % progress bar Percent Text
        pP                      % progress bar Patch
        pA                      % progress bar axis

        meas = Measurement;     % Measurements array
        amix = 0;               % active measurement index
        modix = 1;              % measurement on display index
    end
    
    methods (Access = private)

        % enable/disable aquisition settings
        function setInteractivity(app, state)
            app.AquisitionSettingsPanel.Enable = state;
            app.ConnectivityPanel.Enable = state;
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%     Progress Bar    %%%%%%%%%%%%%%%%%%%%%%%%%%%

        function setupProgressBar(app)
            app.Panel.AutoResizeChildren = 'off'; % To avoid using the whole uifigure space, advisable to place under the panel
            app.pA = subplot(1,1,1,'Parent',app.Panel);
            app.pA.Position=[.1 .4 .8 .05];
            app.pA.Box='on';
            app.pA.XTick=[];
            app.pA.YTick=[];
            app.pA.Color=[0.9375 0.9375 0.9375];
            app.pA.XAxis.Limits=[0,1];
            app.pA.YAxis.Limits=[0,1];
            axtoolbar(app.pA);
            disableDefaultInteractivity(app.pA);
            title(app.pA,'Progress')
            % Create empty patch that will be updated
            app.pP = patch(app.pA,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            % Create the percent-complete text that will be updated
            app.pT = text(app.pA,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right'); 
        end

        function updateProgressBar(app, percent)
            if percent == 0 
                title(app.pA, 'Progress');
            elseif percent == 100
                title(app.pA, 'Completed');
            end
            % update patch size and percentage text
            app.pP.XData = [0 percent/100 percent/100 0]; 
            app.pT.String = sprintf('%.0f%%',percent); 
            drawnow %update graphics
        end

        function checkProgress(app)
            updateProgressBar(app, app.meas(app.amix).progress)
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%     Figure Plot     %%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Label axis, set title
        function labelAxis(app, magAx, phaAx)
            % X-Axis label [frequency (1)/ angular frequency (2)]
            xlabel(magAx, getXLabel(app), 'Interpreter', 'latex')
            xlabel(phaAx, getXLabel(app), 'Interpreter', 'latex')
            % Y-axis label [magnitude (1)/ attenuation (2)]
            if (app.YaxisDropDown.Value == 1) 
                title(magAx, 'Magnitude')
                ylabel(magAx, getYLabel(app), 'Interpreter', 'latex')
            else 
                title(magAx, 'Attenuation')
                if (app.XaxisDropDown.Value == 1)
                    ylabel(magAx, '$a(f)$ [dB]', 'Interpreter', 'latex')
                else
                    ylabel(magAx, '$a(\omega)$ [dB]', 'Interpreter', 'latex')
                end
            end 
            % Y-axis label phase
            title(phaAx, 'Phase')
            ylabel(phaAx, '$\varphi$ [rad]', 'Interpreter', 'latex')
            yticks(phaAx, [-pi -pi/2 0 pi/2 pi ])
            yticklabels(phaAx, {'-\pi','-\pi/2','0','-\pi/2','\pi'})
            ylim(phaAx, [-pi pi])
        end
        
        % Helper function that returns label string for x-axis
        function label = getXLabel(app)
            if (app.XaxisDropDown.Value == 1) 
                label = '$f$ [Hz]';
            else
                label = '$\omega$ [rad/s]';
            end
        end
        
        % Helper function that returns label string for y-axis
        function label = getYLabel(app)
            if (app.XaxisDropDown.Value == 1) % fct of freq or omega
                if (app.PlottypexyDropDown.Value == 1 || app.PlottypexyDropDown.Value == 3) % linear y-axis
                    label = '$|H(f)|$';
                else
                    label = '$|H(f)|$ [dB]';
                end
            else
                if (app.PlottypexyDropDown.Value == 1 || app.PlottypexyDropDown.Value == 3) % linear y-axis
                    label = '$|H(\omega)|$';
                else
                    label = '$|H(\omega)|$ [dB]';
                end
            end
        end
        
        function plotData(app, measObj, magAx, phaAx)
            plot(phaAx, getXAxis(app, measObj.freq, measObj.omega), measObj.phase);
            switch app.PlottypexyDropDown.Value
                case 1 % linear/linear
                    plot(magAx, getXAxis(app, measObj.freq, measObj.omega), getYAxis(app, "linear", measObj.mag, measObj.magdB, measObj.attdB));
                    set(magAx, 'XScale', 'linear');
                    set(phaAx, 'XScale', 'linear');
                case 2 % linear/log
                    plot(magAx, getXAxis(app, measObj.freq, measObj.omega), getYAxis(app, "log", measObj.mag, measObj.magdB, measObj.attdB));
                    set(magAx, 'XScale', 'linear');
                    set(phaAx, 'XScale', 'linear');
                case 3 % log/linear
                    plot(magAx, getXAxis(app, measObj.freq, measObj.omega), getYAxis(app, "linear", measObj.mag, measObj.magdB, measObj.attdB));
                    set(magAx, 'XScale', 'log');
                    set(phaAx, 'XScale', 'log');
                case 4  % log/log
                    plot(magAx, getXAxis(app, measObj.freq, measObj.omega), getYAxis(app, "log", measObj.mag, measObj.magdB, measObj.attdB));
                    set(magAx, 'XScale', 'log');
                    set(phaAx, 'XScale', 'log');
                otherwise
                    error('plottype switchcase undefined')
            end
        end

        function axis = getYAxis(app, type, mag, magdB, attdB)
            if (app.YaxisDropDown.Value == 1)   % magnitude
                if (type == "linear")
                    axis = mag;
                else
                    axis = magdB;
                end
            else                                % attenuation
                axis = attdB;
            end
        end

        function axis = getXAxis(app, freq, omega)
            if (app.XaxisDropDown.Value == 1)   % frequency
                axis = freq;
            else                                % angular freq.
                axis = omega;
            end
        end 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%     
        
        % sets the "measurement on display"
        function setmod(app, newmodix)
            %if app.modix ~= newmodix && isinteger(newmodix)
                len = length(app.meas);
                if  newmodix <= len
                    plotData(app, app.meas(newmodix), app.UIAxes, app.UIAxes2)
                    app.modix = newmodix;
                end
            %end
        end

        function updatemsdd(app)
            len = length(app.meas);
            items = strings(1,len);
            itemsData = zeros(1,len);
            for k = 1:len
                items(k) = append("plot ", int2str(k));
                itemsData(k) = k;
            end
            app.SelectmeasurementDropDown.Items = items;
            app.SelectmeasurementDropDown.ItemsData = itemsData;
            app.SelectmeasurementDropDown.Value = itemsData(app.modix);
        end
    end

    methods (Static)
        function exportData(measObj)
            labels = {  'Measurement object',...
                        'Ch1Vpp',...
                        'Ch2Vpp',...
                        'Magnitude',...
                        'Magnitude [dB]',...
                        'Attenuation',...
                        'Phase',...
                        'Raw phase data',...
                        'Frequency',...
                        'Angular frequency'};
            vars = {    'mObj',...
                        'ch1Vpp',...
                        'ch2Vpp',...
                        'mag',...
                        'magdB',...
                        'att',...
                        'phase',...
                        'rawPhase',...
                        'freq',...
                        'angFreq'};
            vals = {    measObj,...
                        measObj.ch1Vpp,...
                        measObj.ch2Vpp,...
                        measObj.mag,...
                        measObj.magdB,...
                        measObj.attdB,...
                        measObj.phase,...
                        measObj.rawPhase,...
                        measObj.freq,...
                        measObj.omega};
            title = "Export to workspace";
            defs = logical([1 0 0 0 0 0 0 0 0 0]);
            export2wsdlg(labels, vars, vals,title,defs)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            labelAxis(app, app.UIAxes, app.UIAxes2);
            setupProgressBar(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            setInteractivity(app, 'off')
            if isempty(app.meas(1).freq)
                app.amix = 1;                       % override empty obj
            else
                app.amix = length(app.meas) + 1;    % active measurement index
            end
            app.meas(app.amix) = Measurement(app.SamplesEditField.Value,...
                app.StartfrequencyEditField.Value,... 
                app.StopfrequencyEditField.Value,...
                app.SampledistributionDropDown.Value,...
                app.Ch1AttenuationDropDown.Value,... 
                app.Ch2AttenuationDropDown.Value,...
                app.MbandwidthlimitCheckBox.Value,... 
                app.EnhancedautoscalingCheckBox.Value,...
                app.VppEditField.Value,...
                app.VoffEditField.Value,... 
                app.OutputImpedanceDropDown.Value,...
                app.LockfrontpanelsCheckBox.Value);
            t = timer('TimerFcn', @(~,~)checkProgress(app), 'ExecutionMode', 'fixedDelay', 'Period', 1, 'TasksToExecute', inf, 'BusyMode', 'drop');
            start(t)
            makeMeasurement(app.meas(app.amix), app.IPadressEditField.Value, app.IPadressEditField_2.Value)
            stop(t)
            delete(t)
            if app.meas(app.amix).aborted == true
                title(app.pA, 'Aborted')
            else
                updateProgressBar(app, 100)
            end
            setInteractivity(app, 'on')
            setmod(app, app.amix)       % set measurement on display index to active measurement index
            updatemsdd(app)
            app.amix = 0; 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.amix > 0
                title(app.pA, 'Aborting...')
                app.meas(app.amix).abortMeasurement
            end
        end

        % Value changed function: SamplesEditField
        function SamplesEditFieldValueChanged(app, event)

        end

        % Value changed function: StartfrequencyEditField
        function StartfrequencyEditFieldValueChanged(app, event)

        end

        % Value changed function: StopfrequencyEditField
        function StopfrequencyEditFieldValueChanged(app, event)

        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            plotData(app, app.meas(app.modix), app.UIAxes, app.UIAxes2);
            labelAxis(app, app.UIAxes, app.UIAxes2);
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            plotData(app, app.meas(app.modix), app.UIAxes, app.UIAxes2);
            labelAxis(app, app.UIAxes, app.UIAxes2);
        end

        % Value changed function: PlottypexyDropDown
        function PlottypexyDropDownValueChanged(app, event)
            plotData(app, app.meas(app.modix), app.UIAxes, app.UIAxes2);
            labelAxis(app, app.UIAxes, app.UIAxes2);
        end

        % Button pushed function: PrintfigureButton
        function PrintfigureButtonPushed(app, event)
            if (app.AppendtoDropDown.Value == 0)
                figure("Name","Measured Data")
                mAx = subplot(2,1,1);
                pAx = subplot(2,1,2);
            else
                figure(app.AppendtoDropDown.Value);
                ax = findobj( get(gcf,'Children'), '-depth', 1, 'type', 'axes');
                pAx = ax(1);
                mAx = ax(2);
                hold([mAx pAx],'on')
            end
            plotData(app, app.meas(app.modix), mAx, pAx);
            labelAxis(app, mAx, pAx);
            hold([mAx pAx],'off')
        end

        % Drop down opening function: AppendtoDropDown
        function AppendtoDropDownOpening(app, event)
            figArray = findobj(groot, 'type', 'figure');
            nax = arrayfun(@(F)length(findobj(F, 'type', 'axes')), figArray);
            figArray = figArray(nax == 2);
            len = length(figArray);
            items = strings(1,len+1);
            items(1) = "new figure";
            itemsData = zeros(1,len+1);
            if (len >= 0) 
                for k = 1:len
                    items(k+1) = append("Figure ", int2str(get(figArray(k), 'Number')));
                    itemsData(k+1) = get(figArray(k), 'Number');
                end
            end
            app.AppendtoDropDown.Items = items;
            app.AppendtoDropDown.ItemsData = itemsData;
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            bodeplot.exportData(app.meas(app.modix));
        end

        % Value changed function: OutputImpedanceDropDown
        function OutputImpedanceDropDownValueChanged(app, event)
            value = app.OutputImpedanceDropDown.Value;
            dValue = str2double(value);
            if isequal(value,'HighZ') || isequal(value,'50 Ohm')
                return
            elseif dValue >= 1 && dValue <= 10000 && mod(dValue,1) == 0
                return
            end
            uialert(app.UIFigure, 'Value must be an integer between 1 Ohm and 10 kOhm or HighZ', 'Invalid value', 'Icon', 'info')
            app.OutputImpedanceDropDown.Value = 'HighZ';
        end

        % Value changed function: VppEditField
        function VppEditFieldValueChanged(app, event)

        end

        % Value changed function: VoffEditField
        function VoffEditFieldValueChanged(app, event)

        end

        % Value changed function: SelectmeasurementDropDown
        function SelectmeasurementDropDownValueChanged(app, event)
            setmod(app, app.SelectmeasurementDropDown.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Magnitude')
            xlabel(app.UIAxes, 'f [Hz]')
            ylabel(app.UIAxes, '|H(f)| [dB]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Phase')
            xlabel(app.UIAxes2, 'f [Hz]')
            ylabel(app.UIAxes2, 'Phase [rad/pi]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [-1 1];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Panel = uipanel(app.UIFigure);
            app.AquisitionSettingsPanel = uipanel(app.UIFigure);
            app.AquisitionSettingsPanel.Title = 'Aquisition Settings';
            app.MbandwidthlimitCheckBox = uicheckbox(app.AquisitionSettingsPanel);
            app.MbandwidthlimitCheckBox.Text = '20M bandwidth limit';
            app.LockfrontpanelsCheckBox = uicheckbox(app.AquisitionSettingsPanel);
            app.LockfrontpanelsCheckBox.Text = 'Lock frontpanels';
            app.StartfrequencyEditFieldLabel = uilabel(app.AquisitionSettingsPanel);
            app.StartfrequencyEditFieldLabel.Text = 'Start frequency';
            app.StartfrequencyEditField = uieditfield(app.AquisitionSettingsPanel, 'numeric');
            app.StartfrequencyEditField.UpperLimitInclusive = 'off';
            app.StartfrequencyEditField.Limits = [10 Inf];
            app.StartfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @StartfrequencyEditFieldValueChanged, true);
            app.StartfrequencyEditField.Tooltip = {'Start frequency must be > 43 Hz due to auto-scaling limitiations of the Oscilloscope'};
            app.StartfrequencyEditField.Value = 50;
            app.StopfrequencyEditFieldLabel = uilabel(app.AquisitionSettingsPanel);
            app.StopfrequencyEditFieldLabel.Text = 'Stop frequency';
            app.StopfrequencyEditField = uieditfield(app.AquisitionSettingsPanel, 'numeric');
            app.StopfrequencyEditField.UpperLimitInclusive = 'off';
            app.StopfrequencyEditField.Limits = [43 Inf];
            app.StopfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @StopfrequencyEditFieldValueChanged, true);
            app.StopfrequencyEditField.Value = 500000;
            app.SamplesEditFieldLabel = uilabel(app.AquisitionSettingsPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.AquisitionSettingsPanel, 'numeric');
            app.SamplesEditField.Limits = [1 Inf];
            app.SamplesEditField.ValueChangedFcn = createCallbackFcn(app, @SamplesEditFieldValueChanged, true);
            app.SamplesEditField.Value = 20;
            app.EnhancedautoscalingCheckBox = uicheckbox(app.AquisitionSettingsPanel);
            app.EnhancedautoscalingCheckBox.Text = 'Enhanced auto-scaling';
            app.EnhancedautoscalingCheckBox.Value = true;
            app.Ch2AttenuationDropDownLabel = uilabel(app.AquisitionSettingsPanel);
            app.Ch2AttenuationDropDownLabel.Text = 'Ch 2 Attenuation';
            app.Ch2AttenuationDropDown = uidropdown(app.AquisitionSettingsPanel);
            app.Ch2AttenuationDropDown.Items = {'1x', '10x'};
            app.Ch2AttenuationDropDown.ItemsData = [1 10];
            app.Ch2AttenuationDropDown.Value = 1;
            app.Ch1AttenuationDropDownLabel = uilabel(app.AquisitionSettingsPanel);
            app.Ch1AttenuationDropDownLabel.Text = 'Ch 1 Attenuation';
            app.Ch1AttenuationDropDown = uidropdown(app.AquisitionSettingsPanel);
            app.Ch1AttenuationDropDown.Items = {'1x', '10x'};
            app.Ch1AttenuationDropDown.ItemsData = [1 10];
            app.Ch1AttenuationDropDown.Value = 1;
            app.SampledistributionDropDownLabel = uilabel(app.AquisitionSettingsPanel);
            app.SampledistributionDropDownLabel.Text = 'Sample distribution';
            app.SampledistributionDropDown = uidropdown(app.AquisitionSettingsPanel);
            app.SampledistributionDropDown.Items = {'log', 'linear'};
            app.SampledistributionDropDown.Value = 'log';
            app.VppEditFieldLabel = uilabel(app.AquisitionSettingsPanel);
            app.VppEditFieldLabel.Text = 'Vpp';
            app.VppEditField = uieditfield(app.AquisitionSettingsPanel, 'numeric');
            app.VppEditField.Limits = [0.002 20];
            app.VppEditField.ValueChangedFcn = createCallbackFcn(app, @VppEditFieldValueChanged, true);
            app.VppEditField.Tooltip = {'2 mV to 20 V, maxium value depends on frequency and output impedance setting'};
            app.VppEditField.Value = 1;
            app.VoffEditFieldLabel = uilabel(app.AquisitionSettingsPanel);
            app.VoffEditFieldLabel.Text = 'Voff';
            app.VoffEditField = uieditfield(app.AquisitionSettingsPanel, 'numeric');
            app.VoffEditField.Limits = [-7.5 7.5];
            app.VoffEditField.ValueChangedFcn = createCallbackFcn(app, @VoffEditFieldValueChanged, true);
            app.VoffEditField.Tooltip = {'-7.5 V to 7.5 V'};
            app.OutputImpedanceDropDownLabel = uilabel(app.AquisitionSettingsPanel);
            app.OutputImpedanceDropDownLabel.Text = 'Output Impedance';
            app.OutputImpedanceDropDown = uidropdown(app.AquisitionSettingsPanel);
            app.OutputImpedanceDropDown.Items = {'HighZ', '50 Ohm'};
            app.OutputImpedanceDropDown.ItemsData = {'HighZ', '50'};
            app.OutputImpedanceDropDown.Editable = 'on';
            app.OutputImpedanceDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputImpedanceDropDownValueChanged, true);
            app.OutputImpedanceDropDown.Tooltip = {'1 to 10 kOhm, or HighZ'};
            app.OutputImpedanceDropDown.Value = 'HighZ';
            app.NoiseRejectCheckBox = uicheckbox(app.AquisitionSettingsPanel);
            app.NoiseRejectCheckBox.Enable = 'off';
            app.NoiseRejectCheckBox.Text = 'Noise Reject';
            app.NoiseRejectCheckBox.Value = true;
            app.ConnectivityPanel = uipanel(app.UIFigure);
            app.ConnectivityPanel.Title = 'Connectivity';
            app.DS1000ZPanel = uipanel(app.ConnectivityPanel);
            app.DS1000ZPanel.Title = 'DS1000Z';
            app.InterfaceDropDownLabel = uilabel(app.DS1000ZPanel);
            app.InterfaceDropDownLabel.Enable = 'off';
            app.InterfaceDropDownLabel.Text = 'Interface';
            app.InterfaceDropDown = uidropdown(app.DS1000ZPanel);
            app.InterfaceDropDown.Items = {'LAN', 'USB'};
            app.InterfaceDropDown.Enable = 'off';
            app.InterfaceDropDown.Value = 'LAN';
            app.IPadressEditFieldLabel = uilabel(app.DS1000ZPanel);
            app.IPadressEditFieldLabel.Text = 'IP-adress';
            app.IPadressEditField = uieditfield(app.DS1000ZPanel, 'text');
            app.IPadressEditField.Value = '192.168.0.151';
            app.DG1000ZPanel = uipanel(app.ConnectivityPanel);
            app.DG1000ZPanel.Title = 'DG1000Z';
            app.InterfaceDropDown_2 = uidropdown(app.DG1000ZPanel);
            app.InterfaceDropDown_2.Items = {'LAN', 'USB'};
            app.InterfaceDropDown_2.Enable = 'off';
            app.InterfaceDropDown_2.Value = 'LAN';
            app.InterfaceDropDown_2Label = uilabel(app.DG1000ZPanel);
            app.InterfaceDropDown_2Label.Enable = 'off';
            app.InterfaceDropDown_2Label.Text = 'Interface';
            app.IPadressEditField_2Label = uilabel(app.DG1000ZPanel);
            app.IPadressEditField_2Label.Text = 'IP-adress';
            app.IPadressEditField_2 = uieditfield(app.DG1000ZPanel, 'text');
            app.IPadressEditField_2.Value = '192.168.0.163';
            app.DataToolsPanel = uipanel(app.UIFigure);
            app.DataToolsPanel.Title = 'Data Tools';
            app.PlotSettingsPanel = uipanel(app.DataToolsPanel);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.YaxisDropDownLabel = uilabel(app.PlotSettingsPanel);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.PlotSettingsPanel);
            app.YaxisDropDown.Items = {'magnitude', 'attenuation'};
            app.YaxisDropDown.ItemsData = [1 2];
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Value = 1;
            app.PlottypexyLabel = uilabel(app.PlotSettingsPanel);
            app.PlottypexyLabel.Text = 'Plottype (x/y)';
            app.PlottypexyDropDown = uidropdown(app.PlotSettingsPanel);
            app.PlottypexyDropDown.Items = {'linear/linear', 'linear/log', 'log/linear', 'log/log'};
            app.PlottypexyDropDown.ItemsData = [1 2 3 4];
            app.PlottypexyDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottypexyDropDownValueChanged, true);
            app.PlottypexyDropDown.Value = 4;
            app.XaxisDropDownLabel = uilabel(app.PlotSettingsPanel);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.PlotSettingsPanel);
            app.XaxisDropDown.Items = {'frequency', 'angular frequency'};
            app.XaxisDropDown.ItemsData = [1 2];
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 1;
            app.ExportPanel = uipanel(app.DataToolsPanel);
            app.ExportPanel.Title = 'Export';
            app.PrintfigureButton = uibutton(app.ExportPanel, 'push');
            app.PrintfigureButton.ButtonPushedFcn = createCallbackFcn(app, @PrintfigureButtonPushed, true);
            app.PrintfigureButton.Text = 'Print figure';
            app.ExportdataButton = uibutton(app.ExportPanel, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Text = 'Export data';
            app.WorkspaceLabel = uilabel(app.ExportPanel);
            app.WorkspaceLabel.Text = 'Workspace:';
            app.AppendtoDropDownLabel = uilabel(app.ExportPanel);
            app.AppendtoDropDownLabel.Text = 'Append to';
            app.AppendtoDropDown = uidropdown(app.ExportPanel);
            app.AppendtoDropDown.Items = {'new figure'};
            app.AppendtoDropDown.ItemsData = 0;
            app.AppendtoDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AppendtoDropDownOpening, true);
            app.AppendtoDropDown.Value = 0;
            app.SelectmeasurementDropDownLabel = uilabel(app.DataToolsPanel);
            app.SelectmeasurementDropDownLabel.Text = 'Select measurement';
            app.SelectmeasurementDropDown = uidropdown(app.DataToolsPanel);
            app.SelectmeasurementDropDown.Items = {'empty'};
            app.SelectmeasurementDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectmeasurementDropDownValueChanged, true);
            app.SelectmeasurementDropDown.Value = 'empty';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bodeplot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end